classdef Menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        HomeTab                   matlab.ui.container.Tab
        PleaseSelectwhatyouwanttoexamineyourpatientforLabel  matlab.ui.control.Label
        LumpyskinButton           matlab.ui.control.Button
        HeartcomplicationsButton  matlab.ui.control.Button
        DiabetesButton            matlab.ui.control.Button
        GeneralDiseasesButton     matlab.ui.control.Button
        GeneralDiseasesTab_2      matlab.ui.container.Tab
        DiabetesTab               matlab.ui.container.Tab
        HeartcomplicationsTab     matlab.ui.container.Tab
        GeneralButton             matlab.ui.control.Button
        ExactButton               matlab.ui.control.Button
        TextArea                  matlab.ui.control.TextArea
        ExactMeasurementsTab      matlab.ui.container.Tab
        GeneralMeasurementsTab    matlab.ui.container.Tab
        LumpyskinTab              matlab.ui.container.Tab
    end

    
    properties (Access = private)
        HHIML % Description
        HHISY % Description
    end
    
    methods (Access = private)
        
        function startHHI = func(app)
            load('HHIModelTreeBagger.mat','MLmodel', 'all_symptoms_hh'); %load model and symptoms
            
            app.HHIML=MLmodel; %store model in our class
            app.HHISY=all_symptoms_hh; %store symptoms in our class
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GeneralDiseasesButton
        function GeneralDiseasesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralDiseasesTab_2; % Open correlating Tab
        end

        % Button pushed function: DiabetesButton
        function DiabetesButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.DiabetesTab; % Open correlating Tab
        end

        % Button pushed function: HeartcomplicationsButton
        function HeartcomplicationsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.HeartcomplicationsTab; % Open correlating Tab
        end

        % Button pushed function: LumpyskinButton
        function LumpyskinButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.LumpyskinTab; % Open correlating Tab
        end

        % Callback function
        function PredictButtonValueChanged(app, event)
            value = app.PredictButton.Value;
            current_feature=zeros(1, size(app.HHISY,1)); %create a new feature

            %durchgehen aller 21 Variablen
            current_feature(1, 1)=1;
            current_feature(1, 2)=1;
            current_feature(1, 3)=1;
            current_feature(1, 4)=1;
            current_feature(1, 5)=1;
            current_feature(1, 6)=1;
            current_feature(1, 7)=1;
            current_feature(1, 8)=1;
            current_feature(1, 9)=1;
            current_feature(1, 10)=1;
            current_feature(1, 11)=1;
            current_feature(1, 12)=1;
            current_feature(1, 13)=1;
            current_feature(1, 14)=1;
            current_feature(1, 15)=1;
            current_feature(1, 16)=1;
            current_feature(1, 17)=1;
            current_feature(1, 18)=1;
            current_feature(1, 19)=1;
            current_feature(1, 20)=1;
            current_feature(1, 21)=1;
            %current_feature(1, 22)=1;


            app.FeedbackTextArea.Value = predict(app.HHIML,current_feature);
        end

        % Callback function
        function SexDropDownValueChanged(app, event)
            value = app.SexDropDown.Value;
            % gebe 0 oder 1 zurück
            if value == Female
                ret = 1
            end 
        end

        % Button pushed function: ExactButton
        function ExactButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.ExactMeasurementsTab;
        end

        % Button pushed function: GeneralButton
        function GeneralButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.GeneralMeasurementsTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.GeneralDiseasesButton = uibutton(app.HomeTab, 'push');
            app.GeneralDiseasesButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralDiseasesButtonPushed, true);
            app.GeneralDiseasesButton.Text = 'GeneralDiseases';
            app.DiabetesButton = uibutton(app.HomeTab, 'push');
            app.DiabetesButton.ButtonPushedFcn = createCallbackFcn(app, @DiabetesButtonPushed, true);
            app.DiabetesButton.Text = 'Diabetes';
            app.HeartcomplicationsButton = uibutton(app.HomeTab, 'push');
            app.HeartcomplicationsButton.ButtonPushedFcn = createCallbackFcn(app, @HeartcomplicationsButtonPushed, true);
            app.HeartcomplicationsButton.Text = 'Heartcomplications';
            app.LumpyskinButton = uibutton(app.HomeTab, 'push');
            app.LumpyskinButton.ButtonPushedFcn = createCallbackFcn(app, @LumpyskinButtonPushed, true);
            app.LumpyskinButton.Text = 'Lumpyskin';
            app.PleaseSelectwhatyouwanttoexamineyourpatientforLabel = uilabel(app.HomeTab);
            app.PleaseSelectwhatyouwanttoexamineyourpatientforLabel.Text = 'Please Select what you want to examine your patient for:';
            app.GeneralDiseasesTab_2 = uitab(app.TabGroup);
            app.GeneralDiseasesTab_2.Title = 'GeneralDiseases';
            app.DiabetesTab = uitab(app.TabGroup);
            app.DiabetesTab.Title = 'Diabetes';
            app.HeartcomplicationsTab = uitab(app.TabGroup);
            app.HeartcomplicationsTab.Title = 'Heartcomplications';
            app.TextArea = uitextarea(app.HeartcomplicationsTab);
            app.TextArea.Value = {'Do you have exact measurements or do you just want to test for heart diseases in general?'};
            app.ExactButton = uibutton(app.HeartcomplicationsTab, 'push');
            app.ExactButton.ButtonPushedFcn = createCallbackFcn(app, @ExactButtonPushed, true);
            app.ExactButton.Text = 'Exact';
            app.GeneralButton = uibutton(app.HeartcomplicationsTab, 'push');
            app.GeneralButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralButtonPushed, true);
            app.GeneralButton.Text = 'General';
            app.ExactMeasurementsTab = uitab(app.TabGroup);
            app.ExactMeasurementsTab.Title = 'ExactMeasurements';
            app.GeneralMeasurementsTab = uitab(app.TabGroup);
            app.GeneralMeasurementsTab.Title = 'GeneralMeasurements';
            app.LumpyskinTab = uitab(app.TabGroup);
            app.LumpyskinTab.Title = 'Lumpyskin';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end